classdef stimulation_waveform_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SonicationDurationmsEditFieldLabel  matlab.ui.control.Label
        SonicationDurationmsEditField  matlab.ui.control.NumericEditField
        FrequencyMHzEditFieldLabel     matlab.ui.control.Label
        FrequencyMHzEditField          matlab.ui.control.NumericEditField
        DutyCycleLabel                 matlab.ui.control.Label
        DutyCycleEditField             matlab.ui.control.NumericEditField
        PulseRepetitionFrequencyHzEditFieldLabel  matlab.ui.control.Label
        PulseRepetitionFrequencyHzEditField  matlab.ui.control.NumericEditField
        StimulateButton                matlab.ui.control.Button
    end


    properties (Access = public)
        frequency
        duty_cycle
        pulse_repetition_frequency
        duration
        TW
        Resource
    end

    methods (Access = public)
    
        function display_targeting_window(app)
            
%             imWidth = app.Resource.DisplayWindow(1).Position(3); % Position(3) is imWidth, not figure width.
%             imHeight = app.Resource.DisplayWindow(1).Position(4);
%             
%             
%             colormap(app.UIAxes, app.Resource.DisplayWindow(1).Colormap);
%                      
%                       
%             app.UIAxes.Units = 'pixels';
%             app.UIAxes.Position = [app.UIAxes.Position(1) app.UIAxes.Position(2) imWidth imHeight];
%             app.UIAxes.NextPlot = 'replacechildren';
% %             app.UIAxes.Units='normalized';
%             app.UIAxes.YDir = 'reverse';
%             axis(app.UIAxes,'equal');
%             axis(app.UIAxes, 'tight');
%             
%             [x,y,I] = getimage(app.Resource.DisplayWindow.figureHandle);
%             imagesc(app.UIAxes, x,y,I); hold(app.UIAxes, 'on');
%             target_pos = app.Resource.parameters.target_position;
%             %app.Resource.parameters.target_point= impoint(); hold(app.UIAxes, 'on');
%             %(app.UIAxes, 'Position', [25,25,50,50]);%drawpoint(app.UIAxes);
%             %     bringToFront(point)
%             assignin('base','Resource',app.Resource);
%             drawnow
        end
    
        function update_edit_fields(app)
                 app.FrequencyMHzEditField.Value = app.frequency;
                 app.DutyCycleEditField.Value = app.duty_cycle;
                 app.PulseRepetitionFrequencyHzEditField.Value = app.pulse_repetition_frequency;
                 app.SonicationDurationmsEditField.Value = app.duration*1000;           
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Resource)
            app.Resource = Resource;
            app.frequency = app.Resource.parameters.stim_freq;
            app.duty_cycle = app.Resource.parameters.duty_cycle;
            app.duration = app.Resource.parameters.duration;
            app.pulse_repetition_frequency = app.Resource.parameters.prf; 
            update_edit_fields(app)
        end

        % Button pushed function: StimulateButton
        function StimulateButtonPushed(app, event)
           app.TW = waveform_parameters_to_envelope(app.frequency*1e6, app.duty_cycle/100, app.pulse_repetition_frequency, app.duration);
           stimulate_callback(app)
        end

        % Value changed function: SonicationDurationmsEditField
        function SonicationDurationmsEditFieldValueChanged(app, event)
            app.duration = app.SonicationDurationmsEditField.Value/1000;
            update_edit_fields(app)
        end

        % Value changed function: FrequencyMHzEditField
        function FrequencyMHzEditFieldValueChanged(app, event)
            app.frequency = app.FrequencyMHzEditField.Value;
            update_edit_fields(app)
        end

        % Value changed function: DutyCycleEditField
        function DutyCycleEditFieldValueChanged(app, event)
            app.duty_cycle = app.DutyCycleEditField.Value;
            update_edit_fields(app)
        end

        % Value changed function: PulseRepetitionFrequencyHzEditField
        function PulseRepetitionFrequencyHzEditFieldValueChanged(app, event)
            app.pulse_repetition_frequency = app.PulseRepetitionFrequencyHzEditField.Value;
            update_edit_fields(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SonicationDurationmsEditFieldLabel = uilabel(app.UIFigure);
            app.SonicationDurationmsEditFieldLabel.Text = 'Sonication Duration (ms)';
            app.SonicationDurationmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SonicationDurationmsEditField.ValueChangedFcn = createCallbackFcn(app, @SonicationDurationmsEditFieldValueChanged, true);
            app.FrequencyMHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyMHzEditFieldLabel.Text = 'Frequency (MHz)';
            app.FrequencyMHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyMHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyMHzEditFieldValueChanged, true);
            app.DutyCycleLabel = uilabel(app.UIFigure);
            app.DutyCycleLabel.Text = 'Duty Cycle (%)';
            app.DutyCycleEditField = uieditfield(app.UIFigure, 'numeric');
            app.DutyCycleEditField.ValueChangedFcn = createCallbackFcn(app, @DutyCycleEditFieldValueChanged, true);
            app.PulseRepetitionFrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.PulseRepetitionFrequencyHzEditFieldLabel.Text = 'Pulse Repetition Frequency (Hz)';
            app.PulseRepetitionFrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.PulseRepetitionFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @PulseRepetitionFrequencyHzEditFieldValueChanged, true);
            app.StimulateButton = uibutton(app.UIFigure, 'push');
            app.StimulateButton.ButtonPushedFcn = createCallbackFcn(app, @StimulateButtonPushed, true);
            app.StimulateButton.Text = 'Stimulate';
        end
    end

    methods (Access = public)

        % Construct app
        function app = stimulation_waveform_GUI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end